classdef PiezoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ConnectMenu                     matlab.ui.container.Menu
        AutoZeroMenu                    matlab.ui.container.Menu
        ResetPlotMenu                   matlab.ui.container.Menu
        SaveDataMenu                    matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        MovementTab                     matlab.ui.container.Tab
        ResetPlotButton                 matlab.ui.control.Button
        VoltageVSpinner                 matlab.ui.control.Spinner
        VoltageVSpinnerLabel            matlab.ui.control.Label
        PositionmSpinner                matlab.ui.control.Spinner
        PositionmSpinnerLabel           matlab.ui.control.Label
        ProgramTab                      matlab.ui.container.Tab
        EditField_2                     matlab.ui.control.NumericEditField
        StepmEditField                  matlab.ui.control.NumericEditField
        StepmEditFieldLabel             matlab.ui.control.Label
        RepeatsSwitch                   matlab.ui.control.Switch
        RepeatsSwitchLabel              matlab.ui.control.Label
        DurationmsEditField             matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel        matlab.ui.control.Label
        SpeedmsEditField                matlab.ui.control.NumericEditField
        SpeedmsEditFieldLabel           matlab.ui.control.Label
        PiezostepsEditField             matlab.ui.control.NumericEditField
        PiezostepsLabel                 matlab.ui.control.Label
        DurationstartpositionmsEditField  matlab.ui.control.NumericEditField
        DurationstartpositionmsEditFieldLabel  matlab.ui.control.Label
        DurationendpositionmsEditField  matlab.ui.control.NumericEditField
        DurationendpositionmsEditFieldLabel  matlab.ui.control.Label
        EndpositionmEditField           matlab.ui.control.NumericEditField
        EndpositionmEditFieldLabel      matlab.ui.control.Label
        StartpositionmEditField         matlab.ui.control.NumericEditField
        StartpositionmEditFieldLabel    matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Reportfield                     matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        EditField                       matlab.ui.control.NumericEditField
        PositionmLabel                  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Axis = '1'; % Description
        connectedController % Description
        Controller
        % Description
        controllerSerialNumber = '0121012018';
        % Description
        PIdevice
        % Description
        startTime % Description
        connectedControllerName % Description
        fittedModelPosition = load("FittedModel.mat").fittedmodel;
        fittedModelVoltage = load("FittedModelInverted.mat").fittedmodelInvert;
        timeForOneCommand = 0.016; % seconds. checked over 1000 instructions
        isConnected = false;
        actuatorlastPosition = 0;
        actuatorPosition = 0;
        linePlot;
        isRunning = false;
        
    end
    
    methods (Access = private)
        
        function programmPiezoActuator(app, Time_1, Time_2, Position_1, Position_2, steps)
            Voltage_1 = position2voltage(app, Position_1);
            Voltage_2 = position2voltage(app, Position_2);
            step = abs(Voltage_1- Voltage_2) / steps;
            
            app.Reportfield.Value = "Program Started";
            
            if (app.RepeatsSwitch.Value == false)
                while app.isRunning == true
                    app.Reportfield.Value = "Moving Actuator";
                    paintProgramBeforePlot(app, Position_1)
                    tic
                    for i = Voltage_1:step:Voltage_2
                        movePiezoActuator(app, i);
                    end
                    toc
                    paintProgramAfterPlot(app, Position_2)
                    app.actuatorPosition = Position_2;
                    app.Reportfield.Value = ("Finished Moving");
                    pause(Time_1/1000);
                    
                    app.Reportfield.Value = "Moving Actuator";
                    paintProgramBeforePlot(app, Position_2)
                    tic
                    for i = Voltage_2:-step:Voltage_1
                        movePiezoActuator(app, i);
                    end
                    toc
                    paintProgramAfterPlot(app, Position_1)
                    app.actuatorPosition = Position_1;
                    app.Reportfield.Value = ("Finished Moving");
                    pause(Time_2/1000);
                end
            else
                for i =1:app.EditField_2.Value
                    app.Reportfield.Value = "Moving Actuator";
                    paintProgramBeforePlot(app, Position_1)
                    tic
                    for i = Voltage_1:step:Voltage_2
                        movePiezoActuator(app, i);
                    end
                    toc
                    paintProgramAfterPlot(app, Position_2)
                    app.actuatorPosition = Position_2;
                    app.Reportfield.Value = ("Finished Moving");
                    pause(Time_1/1000);
                    
                    app.Reportfield.Value = "Moving Actuator";
                    paintProgramBeforePlot(app, Position_2)
                    tic
                    for i = Voltage_2:-step:Voltage_1
                        movePiezoActuator(app, i);
                    end
                    toc
                    paintProgramAfterPlot(app, Position_1)
                    app.actuatorPosition = Position_1;
                    app.Reportfield.Value = ("Finished Moving");
                    pause(Time_2/1000);
                end
            end


            app.Reportfield.Value = "Program Stopped";
        end
        
        
        function movePiezoActuator(app, Voltage)
            
            app.PIdevice.SVA(app.Axis, Voltage);
            
            
        end
        
        function autoZero(app)
            app.PIdevice.ATZ(app.Axis)
            app.actuatorPosition = 0;
            paintPlot(app, 0);
            app.Reportfield.Value = "Ran AutoZero procedure";
            
        end
        
        function connectController(app)
            
            
            %addpath(genpath("C:\Program Files (x86)\Physik Instrumente (PI)\Software Suite\MATLAB_Driver"));
            app.Reportfield.Value = "MATLAB Driver added";
            % Load PI_GCS_Controller if not already loaded
            if(~exist('Controller','var'))
                app.Controller = PI_GCS_Controller();
            end
            if(~isa(app.Controller,'PI_GCS_Controller'))
                app.Controller = PI_GCS_Controller();
            end
            
            app.Reportfield.Value = "GCS contoller loaded";
            
            try
                boolPIdeviceConnected = false;
                if ( exist ( 'PIdevice', 'var' ) ), if ( app.PIdevice.IsConnected ), boolPIdeviceConnected = true; end; end
                if ( ~(boolPIdeviceConnected ) )
                    app.PIdevice = app.Controller.ConnectUSB ( app.controllerSerialNumber );
                end
                
                app.connectedControllerName = app.PIdevice.qIDN();
                
                app.Reportfield.Value = "Controller connected";
                
                
                % initialize PIdevice object for use in MATLAB
                app.PIdevice = app.PIdevice.InitializeController ();
                app.Reportfield.Value = "PIdevice initialized";
                app.isConnected = true;
            catch
                app.Reportfield.Value = "Initialisation failed. Restart Controller and Software";
            end
            
            app.linePlot = animatedline(app.UIAxes);
            app.startTime = datetime("now");
            
        end
        
        function paintPlot(app, position)
            time = seconds(datetime("now")-app.startTime);
            addpoints(app.linePlot,time , app.actuatorlastPosition);
            addpoints(app.linePlot,time , position);
            app.EditField.Value = position;
        end
        
        function paintProgramBeforePlot(app, position)
            time = seconds(datetime("now")-app.startTime);
            addpoints(app.linePlot,time , position);
            app.EditField.Value = position;
        end
        
        function paintProgramAfterPlot(app, position)
            time = seconds(datetime("now")-app.startTime);
            addpoints(app.linePlot,time , position);
            app.EditField.Value = position;
        end
        
        function resetPlot(app)
            clearpoints(app.linePlot);
        end
        
        function voltage = position2voltage(app, position)
            
            voltage = app.fittedModelVoltage(position);
            if(voltage < 0.00001)
                voltage = 0; end
        end


        function position = voltage2position(app, voltage)
            
            position = app.fittedModelPosition(voltage);
            if(position < 0)
                position = 0; end
            
            
        end
        
        function steps = calculateSteps(app, distance, speed)
            %v = d/t
            %t = steps * 0.016
            %v = d / (steps*0.016)
            %steps = d/(0.016*v)
            steps = distance / (speed * app.timeForOneCommand * 1000); %Milliseconds
        end
        
        
        function speed = calculateSpeed(app, distance, steps)
            speed = distance/(steps* app.timeForOneCommand); % Milliseconds
        end
        
        function time = calculateTime(app, steps)
            time = steps * app.timeForOneCommand * 1000;
        end
        
        
        function steps = calculateStepsFromTime(app, time)
            steps = time / (app.timeForOneCommand * 1000);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            diary myDiaryFile;
        end

        % Menu selected function: ConnectMenu
        function ConnectMenuSelected(app, event)
            connectController(app);
        end

        % Menu selected function: AutoZeroMenu
        function AutoZeroMenuSelected(app, event)
            if app.isConnected == true
                autoZero(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.isConnected == true
                app.PIdevice.CloseConnection();
                %% If you want to unload the dll and destroy the class object
                app.Controller.Destroy ();
                clear Controller;
                clear PIdevice;
            end
            delete(app)
            
        end

        % Value changed function: PositionmSpinner
        function PositionmSpinnerValueChanged(app, event)
            if app.isConnected == true
                
                value = app.PositionmSpinner.Value;
                Voltage = app.fittedModelVoltage(value);
                app.actuatorlastPosition = app.actuatorPosition;
                movePiezoActuator(app, Voltage);
                app.actuatorPosition = value;
                paintPlot(app,value);
                app.VoltageVSpinner.Value = Voltage;
                app.Reportfield.Value = strcat("Moved to position: ", string(value));
            end
        end

        % Value changed function: VoltageVSpinner
        function VoltageVSpinnerValueChanged(app, event)
            if app.isConnected == true
                
                value = app.VoltageVSpinner.Value;
                Position = app.fittedModelPosition(value);
                app.actuatorlastPosition = app.actuatorPosition;
                movePiezoActuator(app, value);
                app.actuatorPosition = Position;
                paintPlot(app,Position);
                app.PositionmSpinner.Value = Position;
                app.Reportfield.Value = strcat("Moved to position: ", string(value));
            end
        end

        % Value changed function: PiezostepsEditField
        function PiezostepsEditFieldValueChanged(app, event)
            value = app.PiezostepsEditField.Value;
            speed = calculateSpeed(app, abs(app.StartpositionmEditField.Value - app.EndpositionmEditField.Value), value);
            time = calculateTime(app, value);
            app.SpeedmsEditField.Value = speed;
            app.DurationmsEditField.Value = time;
        end

        % Value changed function: SpeedmsEditField
        function SpeedmsEditFieldValueChanged(app, event)
            value = app.SpeedmsEditField.Value;
            steps = calculateSteps(app, abs(app.StartpositionmEditField.Value - app.EndpositionmEditField.Value), value);
            if(steps < 1)
                uialert(app.UIFigure,"There cannot be less than 1 steps!" ,"Warning", 'Icon', 'warning');
                steps = 1;
                speed = calculateSpeed(app, abs(app.StartpositionmEditField.Value - app.EndpositionmEditField.Value), steps);
                time = calculateTime(app, steps);
            end
            if mod(steps, 1) ~= 0
                uialert(app.UIFigure,"Steps need to be a whole number! Steps will be rounded!" ,"Warning", 'Icon', 'warning');
                steps = round(steps);
                speed = calculateSpeed(app, abs(app.StartpositionmEditField.Value - app.EndpositionmEditField.Value), steps);
                time = calculateTime(app, steps);
            end
            
            app.DurationmsEditField.Value = time;
            app.SpeedmsEditField.Value = speed;
            app.PiezostepsEditField.Value = steps;
        end

        % Value changed function: DurationmsEditField
        function DurationmsEditFieldValueChanged(app, event)
            value = app.DurationmsEditField.Value;
            steps = calculateStepsFromTime(app, value);
            speed = calculateSpeed(app, abs(app.StartpositionmEditField.Value - app.EndpositionmEditField.Value), steps);
            time = value;
            if(steps < 1)
                uialert(app.UIFigure,"There cannot be less than 1 steps!" ,"Warning", 'Icon', 'warning');
                steps = 1;
                speed = calculateSpeed(app, abs(app.StartpositionmEditField.Value - app.EndpositionmEditField.Value), steps);
                time = calculateTime(app, steps);
            end
            if mod(steps, 1) ~= 0
                uialert(app.UIFigure,"Steps need to be a whole number! Steps will be rounded!" ,"Warning", 'Icon', 'warning');
                steps = round(steps);
                speed = calculateSpeed(app, abs(app.StartpositionmEditField.Value - app.EndpositionmEditField.Value), steps);
                time = calculateTime(app, steps);
                
            end
            app.DurationmsEditField.Value = time;
            app.SpeedmsEditField.Value = speed;
            app.PiezostepsEditField.Value = steps;
            
        end

        % Callback function: ResetPlotButton, ResetPlotMenu
        function ResetPlotButtonPushed(app, event)
            resetPlot(app);
            app.startTime = datetime("now");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isRunning = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.isConnected == true
                app.isRunning = true;
                programmPiezoActuator(app, app.DurationendpositionmsEditField.Value, app.DurationstartpositionmsEditField.Value, app.StartpositionmEditField.Value, app.EndpositionmEditField.Value, app.PiezostepsEditField.Value);
            end
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            [data_x, data_y] = getpoints(app.linePlot);
            data = [data_x' data_y'];
            data = array2table(data, 'VariableNames',{'time(s)','position (µ)'});
            [file, path] = uiputfile({'*.csv'; '*.tsv'});
            writetable(data, fullfile(path,file))
        end

        % Value changed function: StartpositionmEditField
        function StartpositionmEditFieldValueChanged(app, event)
            value = app.StartpositionmEditField.Value;
            app.StartpositionmEditField.Value = round(value,5,"significant");
        end

        % Value changed function: StepmEditField
        function StepmEditFieldValueChanged(app, event)
            value = app.StepmEditField.Value;
            app.StepmEditField.Value = round(value,5,"significant");
            app.EndpositionmEditField.Value = app.StartpositionmEditField.Value + value;
        end

        % Value changed function: EndpositionmEditField
        function EndpositionmEditFieldValueChanged(app, event)
            value = app.EndpositionmEditField.Value;
            app.EndpositionmEditField.Value  = round(value,5,"significant");
            app.StepmEditField.Value = value - app.StartpositionmEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {526, 526};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {305, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ConnectMenu = uimenu(app.Menu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectMenuSelected, true);
            app.ConnectMenu.Text = 'Connect';
            app.AutoZeroMenu = uimenu(app.Menu);
            app.AutoZeroMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoZeroMenuSelected, true);
            app.AutoZeroMenu.Text = 'AutoZero';
            app.ResetPlotMenu = uimenu(app.Menu);
            app.ResetPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotMenu.Text = 'Reset Plot';
            app.SaveDataMenu = uimenu(app.Menu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Text = 'Save Data';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Reportfield = uieditfield(app.LeftPanel, 'text');
            app.Reportfield.Editable = 'off';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MovementTab = uitab(app.TabGroup);
            app.MovementTab.Title = 'Movement';
            app.PositionmSpinnerLabel = uilabel(app.MovementTab);
            app.PositionmSpinnerLabel.Text = 'Position (μm)';
            app.PositionmSpinner = uispinner(app.MovementTab);
            app.PositionmSpinner.Step = 0.003;
            app.PositionmSpinner.Limits = [0 35];
            app.PositionmSpinner.ValueChangedFcn = createCallbackFcn(app, @PositionmSpinnerValueChanged, true);
            app.PositionmSpinner.Tooltip = {'Resolution: 0,3 nm'};
            app.VoltageVSpinnerLabel = uilabel(app.MovementTab);
            app.VoltageVSpinnerLabel.Text = 'Voltage (V)';
            app.VoltageVSpinner = uispinner(app.MovementTab);
            app.VoltageVSpinner.Step = 0.1;
            app.VoltageVSpinner.Limits = [0 105];
            app.VoltageVSpinner.ValueChangedFcn = createCallbackFcn(app, @VoltageVSpinnerValueChanged, true);
            app.ResetPlotButton = uibutton(app.MovementTab, 'push');
            app.ResetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotButton.Text = 'Reset Plot';
            app.ProgramTab = uitab(app.TabGroup);
            app.ProgramTab.Title = 'Program';
            app.StartButton = uibutton(app.ProgramTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ProgramTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.StartpositionmEditFieldLabel = uilabel(app.ProgramTab);
            app.StartpositionmEditFieldLabel.Text = 'Start position (μm)';
            app.StartpositionmEditField = uieditfield(app.ProgramTab, 'numeric');
            app.StartpositionmEditField.Limits = [0 30];
            app.StartpositionmEditField.ValueDisplayFormat = '%.4f';
            app.StartpositionmEditField.ValueChangedFcn = createCallbackFcn(app, @StartpositionmEditFieldValueChanged, true);
            app.StartpositionmEditField.Tooltip = {'Resolution: 0,3 nm'};
            app.EndpositionmEditFieldLabel = uilabel(app.ProgramTab);
            app.EndpositionmEditFieldLabel.Text = 'End position (μm)';
            app.EndpositionmEditField = uieditfield(app.ProgramTab, 'numeric');
            app.EndpositionmEditField.Limits = [0 30];
            app.EndpositionmEditField.ValueDisplayFormat = '%.4f';
            app.EndpositionmEditField.ValueChangedFcn = createCallbackFcn(app, @EndpositionmEditFieldValueChanged, true);
            app.EndpositionmEditField.Tooltip = {'Resolution: 0,3 nm'};
            app.DurationendpositionmsEditFieldLabel = uilabel(app.ProgramTab);
            app.DurationendpositionmsEditFieldLabel.Text = 'Duration end position (ms)';
            app.DurationendpositionmsEditField = uieditfield(app.ProgramTab, 'numeric');
            app.DurationendpositionmsEditField.Limits = [0 Inf];
            app.DurationstartpositionmsEditFieldLabel = uilabel(app.ProgramTab);
            app.DurationstartpositionmsEditFieldLabel.Text = 'Duration start position (ms)';
            app.DurationstartpositionmsEditField = uieditfield(app.ProgramTab, 'numeric');
            app.DurationstartpositionmsEditField.Limits = [0 Inf];
            app.PiezostepsLabel = uilabel(app.ProgramTab);
            app.PiezostepsLabel.Text = 'Piezo steps #';
            app.PiezostepsEditField = uieditfield(app.ProgramTab, 'numeric');
            app.PiezostepsEditField.Limits = [1 Inf];
            app.PiezostepsEditField.ValueChangedFcn = createCallbackFcn(app, @PiezostepsEditFieldValueChanged, true);
            app.PiezostepsEditField.Value = 1;
            app.SpeedmsEditFieldLabel = uilabel(app.ProgramTab);
            app.SpeedmsEditFieldLabel.Text = 'Speed (μm / s)';
            app.SpeedmsEditField = uieditfield(app.ProgramTab, 'numeric');
            app.SpeedmsEditField.Limits = [0 Inf];
            app.SpeedmsEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedmsEditFieldValueChanged, true);
            app.DurationmsEditFieldLabel = uilabel(app.ProgramTab);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.DurationmsEditField = uieditfield(app.ProgramTab, 'numeric');
            app.DurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationmsEditFieldValueChanged, true);
            app.RepeatsSwitchLabel = uilabel(app.ProgramTab);
            app.RepeatsSwitchLabel.Text = 'Repeats';
            app.RepeatsSwitch = uiswitch(app.ProgramTab, 'slider');
            app.RepeatsSwitch.Items = {'∞', ''};
            app.RepeatsSwitch.ItemsData = [false true];
            app.RepeatsSwitch.Value = false;
            app.StepmEditFieldLabel = uilabel(app.ProgramTab);
            app.StepmEditFieldLabel.Text = 'Step (μm)';
            app.StepmEditField = uieditfield(app.ProgramTab, 'numeric');
            app.StepmEditField.Limits = [0 30];
            app.StepmEditField.ValueDisplayFormat = '%.4f';
            app.StepmEditField.ValueChangedFcn = createCallbackFcn(app, @StepmEditFieldValueChanged, true);
            app.StepmEditField.Tooltip = {'Resolution: 0,3 nm'};
            app.EditField_2 = uieditfield(app.ProgramTab, 'numeric');
            app.EditField_2.Limits = [1 Inf];
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Position (μm)')
            zlabel(app.UIAxes, 'Z')
            app.PositionmLabel = uilabel(app.RightPanel);
            app.PositionmLabel.Text = 'Position (μm)';
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PiezoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end